classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        RunButton          matlab.ui.control.Button
        GridLayout2        matlab.ui.container.GridLayout
        openFileBtn        matlab.ui.control.Button
        saveBtn            matlab.ui.control.Button
        SignalEditorLabel  matlab.ui.control.Label
        removeBtn          matlab.ui.control.Button
        addBtn             matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: addBtn
        function addRow(app, event)
            data = app.UITable.Data;
            if isempty(data)
                data = {};
            end

            % Define ranges
            tRange = 0:1:10;
            xRange = 0:0.15:0.25;
            yRange = 0:0.01:0.11;

            % Add a new row with the default values within the range
            newRow = {tRange(1), xRange(1), yRange(1), 0.11};
            data = [data; newRow];
            app.UITable.Data = data;
        end

        % Button pushed function: removeBtn
        function removeRow(app, event)
            % Get current data
            data = app.UITable.Data;
            if isempty(data)
                return;
            end
        
            % Get the selected
            selected = app.UITable.Selection;
            if isempty(selected)
                return;
            end
            
            rows = selected(1);

            % Remove the selected rows
            data(rows, :) = [];
            app.UITable.Data = data;
        end

        % Cell edit callback: UITable
        function cellEdited(app, event)
            % Define ranges
            tRange = 0:1:10;
            xRange = 0:0.1:0.35;
            yRange = 0:0.01:0.11;
        
            % Get the edited data
            indices = event.Indices;
            row = indices(1);
            col = indices(2);
            newData = event.NewData;
            oldData = event.PreviousData;
        
            % Check and adjust the new data based on the range
            if col == 1 % t column
                if newData < tRange(1) || newData > tRange(end)
                    % If new data is out of range, revert to old data or closest boundary value
                    app.UITable.Data{row, col} = max(min(newData, tRange(end)), tRange(1));
                else
                    app.UITable.Data{row, col} = newData;
                end
            elseif col == 2 % x column
                if newData < xRange(1) || newData > xRange(end)
                    % If new data is out of range, revert to old data or closest boundary value
                    app.UITable.Data{row, col} = max(min(newData, xRange(end)), xRange(1));
                else
                    app.UITable.Data{row, col} = newData;
                end
            elseif col == 3 % y column
                if newData < yRange(1) || newData > yRange(end)
                    % If new data is out of range, revert to old data or closest boundary value
                    app.UITable.Data{row, col} = max(min(newData, yRange(end)), yRange(1));
                else
                    app.UITable.Data{row, col} = newData;
                end
            end
         
            % Get the current data from the table
            data = app.UITable.Data;
            
            if isempty(data)
                return;
            end
            
            % Extract x y z data
            xData = cell2mat(data(:, 2));
            yData = cell2mat(data(:, 3));
            % zData = cell2mat(data(:, 4)); TODO
            
            % Clear existing plot
            cla(app.UIAxes);
            
            % Plot the data
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, xData, yData, '-o');
            hold(app.UIAxes, 'off');
            
            % Set axes labels
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
            zlabel(app.UIAxes, 'z');

            rotate3d(app.UIAxes, 'on');
        end

        % Button pushed function: saveBtn
        function saveSignal(app, event)
            % Get the current data from the table
            data = app.UITable.Data;
        
            % Convert the table data to an array
            dataArray = cell2mat(data);
        
            % Create time and signal data
            t = dataArray(:, 1);
            x = dataArray(:, 2);
            y = dataArray(:, 3);
            z = dataArray(:, 4);
        
            % Create timeseries objects
            ts_x = timeseries(x, t, 'Name', 'x');
            ts_y = timeseries(y, t, 'Name', 'y');
            ts_z = timeseries(z, t, 'Name', 'z');
        
            % Create a Simulink.SimulationData.Dataset object
            signalData = Simulink.SimulationData.Dataset;
            signalData = signalData.addElement(ts_x);
            signalData = signalData.addElement(ts_y);
            signalData = signalData.addElement(ts_z);
        
            % Open a dialog to get the file name and path from the user
            [file, path] = uiputfile('*.mat', 'Save Signal Data As');
        
            % Check if the user canceled the dialog
            if isequal(file, 0)
                disp('User canceled the save operation.');
                return;
            end
        
            % Construct the full file name
            filename = fullfile(path, file);
        
            % Save the signal dataset to a .mat file
            save(filename, 'signalData');
         
        end

        % Button pushed function: openFileBtn
        function openFile(app, event)
            % Open a dialog to select the file
            [file, path] = uigetfile('*.mat', 'Select Signal Data File');
            
            % Check if the user canceled the dialog
            if isequal(file, 0)
                disp('User canceled the file selection.');
                return;
            end
            
            % Construct the full file name
            filename = fullfile(path, file);
            
            % Load the signal dataset from the .mat file
            loadedData = load(filename);
            
            % Check if the loaded data contains signalData
            if isfield(loadedData, 'signalData')
                signalData = loadedData.signalData;
                
                % Extract timeseries data
                ts_x = signalData.get('x');
                ts_y = signalData.get('y');
                ts_z = signalData.get('z');
                
                % Get time, x, and y data
                t = ts_x.Time;
                x = ts_x.Data;
                y = ts_y.Data;
                z = ts_z.Data;
                
                % Update the table with the loaded data
                data = num2cell([t, x, y, z]);
                app.UITable.Data = data;
       
                % It is not letting define functions so I copy-pasted this
                % here 
                if isempty(data)
                    return;
                end
                
                % Extract x y z data
                xData = cell2mat(data(:, 2));
                yData = cell2mat(data(:, 3));
                % zData = cell2mat(data(:, 4)); TODO
                
                % Clear existing plot
                cla(app.UIAxes);
                
                % Plot the data
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, xData, yData, '-o');
                hold(app.UIAxes, 'off');
                
                % Set axes labels
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'y');
                zlabel(app.UIAxes, 'z');
    
                rotate3d(app.UIAxes, 'on');
                
                % it unmimizes the app
                figure(app.UIFigure);

            else
                uialert(app.UIFigure, 'The selected file does not contain valid signal data.', 'File Error');
            end           
        end

        % Button pushed function: RunButton
        function runTheSimulation(app, event)
            data = app.UITable.Data;
            
            if isempty(data)
                return;
            end
           
            % Extract t, x, y, z data
            tData = cell2mat(data(:, 1));
            xData = cell2mat(data(:, 2));
            yData = cell2mat(data(:, 3));
            zData = cell2mat(data(:, 4));
            
            % Concatenate time data to form 2D matrices
            x = [tData, xData];
            y = [tData, yData];
            z = [tData, zData];
        
            % Assign data to workspace variables
            assignin('base', 'x', x);
            assignin('base', 'y', y);
            assignin('base', 'z', z);

            model = 'inverse_kinematics';
            open_system(model);
            in = Simulink.SimulationInput(model);
            out = sim(in);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Coordinates')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'t'; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true false];
            app.UITable.ColumnEditable = [true true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @cellEdited, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.addBtn = uibutton(app.GridLayout2, 'push');
            app.addBtn.ButtonPushedFcn = createCallbackFcn(app, @addRow, true);
            app.addBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'square.png');
            app.addBtn.Tooltip = {'Add row'};
            app.addBtn.Text = '';
            app.removeBtn = uibutton(app.GridLayout2, 'push');
            app.removeBtn.ButtonPushedFcn = createCallbackFcn(app, @removeRow, true);
            app.removeBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'trash.png');
            app.removeBtn.Text = '';
            app.SignalEditorLabel = uilabel(app.GridLayout2);
            app.SignalEditorLabel.Text = 'Signal Editor';
            app.saveBtn = uibutton(app.GridLayout2, 'push');
            app.saveBtn.ButtonPushedFcn = createCallbackFcn(app, @saveSignal, true);
            app.saveBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'floppy-disk.png');
            app.saveBtn.Text = '';
            app.openFileBtn = uibutton(app.GridLayout2, 'push');
            app.openFileBtn.ButtonPushedFcn = createCallbackFcn(app, @openFile, true);
            app.openFileBtn.Icon = fullfile(pathToMLAPP, 'imgs', 'folder.png');
            app.openFileBtn.Text = '';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runTheSimulation, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'imgs', 'play.png');
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end